classdef Demo_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DownsamplePanel              matlab.ui.container.Panel
        ScaleDropDown                matlab.ui.control.DropDown
        ScaleDropDownLabel           matlab.ui.control.Label
        UpsampleMethodDropDown       matlab.ui.control.DropDown
        UpsampleMethodDropDownLabel  matlab.ui.control.Label
        SSIMButton                   matlab.ui.control.Button
        PSNRButton                   matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        SSIMTextArea                 matlab.ui.control.TextArea
        SSIMTextAreaLabel            matlab.ui.control.Label
        PSNRTextArea                 matlab.ui.control.TextArea
        PSNRTextAreaLabel            matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        ClearAllButton               matlab.ui.control.Button
        InputImageButton             matlab.ui.control.Button
        ShapeParameterPanel          matlab.ui.container.Panel
        ResetParameterButton         matlab.ui.control.Button
        ScalingFactorEditField       matlab.ui.control.NumericEditField
        ScalingFactorEditFieldLabel  matlab.ui.control.Label
        GammaEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel          matlab.ui.control.Label
        BetaEditField                matlab.ui.control.NumericEditField
        BetaEditFieldLabel           matlab.ui.control.Label
        AlphaEditField               matlab.ui.control.NumericEditField
        AlphaEditFieldLabel          matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        ClearModifiedImageButton     matlab.ui.control.Button
        SuperResolutionImageLabel    matlab.ui.control.Label
        OriginalImageLabel           matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        im
        im_h
        FileName
        PathName
        umethod
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            [app.FileName,app.PathName]=uigetfile({'*';},'select image file');
            %global im;
            if isequal(app.FileName,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.im=imread([app.PathName,app.FileName]);
            imshow(app.im,'Parent',app.UIAxes);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            cla(app.UIAxes_2,"reset");
            app.PSNRTextArea.Value = '';
            app.SSIMTextArea.Value = '';
            clear all;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.im=imread([app.PathName,app.FileName]);
            [m n ch] = size(app.im);

            alpha = app.AlphaEditField.Value;
            beta = app.BetaEditField.Value;
            gamma = app.GammaEditField.Value;
            scaling = app.ScalingFactorEditField.Value;
            
            % Choose downsample method and scaling from input
            switch app.UpsampleMethodDropDown.Value
                case 'Fraktal'
                    app.umethod = 1;
                case 'Bicubic'
                    app.umethod = 2;
                case 'Bilinier'
                    app.umethod = 3;
            end

            switch app.ScaleDropDown.Value
                case '2'
                    scale = 2;
                case '3'
                    scale = 3;
                case '4'
                    scale = 4;
            end

            %  downsample
                if app.umethod == 1
                    transform = app.im(1:scale:end,1:scale:end,:);
                    Rsize = size(transform,1);
                    Csize = size(transform,2);
                    Rfactor = size((1:2:Rsize),2);
                    Cfactor = size((1:2:Csize),2);
                    if scale==2
                        Rsize = 4*Rfactor;
                        Csize = 4*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    elseif scale==3
                        Rsize = 6*Rfactor;
                        Csize = 6*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    else
                        Rsize = 8*Rfactor;
                        Csize = 8*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    end
                    LR  =  app.im(1:scale:end,1:scale:end,:);  

                    if  ch == 3
                    % change color space, work on illuminance only
                    im_l_ycbcr = rgb2ycbcr(LR);
                    im_l_y = im_l_ycbcr(:, :, 1);
                    im_l_cb = im_l_ycbcr(:, :, 2);
                    im_l_cr = im_l_ycbcr(:, :, 3);
                    im_l_y=double(im_l_y);
                    im_l_cb=double(im_l_cb);
                    im_l_cr=double(im_l_cr);
                    %expand the metrix
                    [m n]=size(im_l_y);
                    II(1:m,1:n) = im_l_y;
                    II(m+1,:) = 2.*II(m,:) - II(m-1,:);
                    II(:,n+1) = 2.*II(:,n) - II(:,n-1);
                    II(m+2,:) = 2.*II(m+1,:) - II(m,:);
                    II(:,n+2) = 2.*II(:,n+1) - II(:,n);
                    II(m+3,:) =2.*II(m+2,:)-II(m+1,:);
                    II(:,n+3) =2.*II(:,n+2)-II(:,n+1);
                    II(m+4,:) =2.*II(m+3,:)-II(m+2,:);
                    II(:,n+4) =2.*II(:,n+3)-II(:,n+2);
                    
                    % image super-resolution
                    im_h_y = main_function(II,m,n,scale,alpha,beta,gamma,scaling);
                % upscale the chrominance simply by "bicubic" 
                [nrow, ncol] = size(im_h_y);
                im_h_cb = imresize(im_l_cb, [nrow, ncol], 'bicubic');
                im_h_cr = imresize(im_l_cr, [nrow, ncol], 'bicubic');
                % change color space back to RGB
                im_h_ycbcr = zeros([nrow, ncol, 3]);
                im_h_ycbcr(:, :, 1) = im_h_y;
                im_h_ycbcr(:, :, 2) = im_h_cb;
                im_h_ycbcr(:, :, 3) = im_h_cr;
                app.im_h = ycbcr2rgb(uint8(im_h_ycbcr));

                else

                    I=double(LR);
                    %expand the metrix
                    [m n]=size(I);
                    II(1:m,1:n) = I;
                    II(m+1,:) = 2.*II(m,:) - II(m-1,:);
                    II(:,n+1) = 2.*II(:,n) - II(:,n-1);
                    II(m+2,:) = 2.*II(m+1,:) - II(m,:);
                    II(:,n+2) = 2.*II(:,n+1) - II(:,n);
                    II(m+3,:) =2.*II(m+2,:)-II(m+1,:);
                    II(:,n+3) =2.*II(:,n+2)-II(:,n+1);
                    II(m+4,:) =2.*II(m+3,:)-II(m+2,:);
                    II(:,n+4) =2.*II(:,n+3)-II(:,n+2);
                
                    % image super-resolution
                    app.im_h= main_function(II,m,n,scale,alpha,beta,gamma,scaling);
                    app.im_h=uint8(app.im_h);
                end
                figure(app.UIFigure);
                imshow(app.im_h,'Parent',app.UIAxes_2);
                end
                if app.umethod ==2
                    transform = app.im(1:scale:end,1:scale:end,:);
                    Rsize = size(transform,1);
                    Csize = size(transform,2);
                    Rfactor = size((1:2:Rsize),2);
                    Cfactor = size((1:2:Csize),2);
                    if scale==2
                        Rsize = 4*Rfactor;
                        Csize = 4*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    elseif scale==3
                        Rsize = 6*Rfactor;
                        Csize = 6*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    else
                        Rsize = 8*Rfactor;
                        Csize = 8*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    end
                    LR  =  app.im(1:scale:end,1:scale:end,:);  

                    if  ch == 3
                    % change color space, work on illuminance only
                    im_l_ycbcr = rgb2ycbcr(LR);
                    im_l_y = im_l_ycbcr(:, :, 1);
                    im_l_cb = im_l_ycbcr(:, :, 2);
                    im_l_cr = im_l_ycbcr(:, :, 3);
                    im_l_y=double(im_l_y);
                    im_l_cb=double(im_l_cb);
                    im_l_cr=double(im_l_cr);
                    
                    im_h_y = imresize(im_l_y, scale, 'bicubic');
                    im_h_cb = imresize(im_l_cb, scale, 'bicubic');
                    im_h_cr = imresize(im_l_cr, scale, 'bicubic');
                    
                    [nrow, ncol] = size(im_h_y);
                    im_h_ycbcr = zeros([nrow, ncol, 3]);
                    im_h_ycbcr(:, :, 1) = im_h_y;
                    im_h_ycbcr(:, :, 2) = im_h_cb;
                    im_h_ycbcr(:, :, 3) = im_h_cr;
                    app.im_h = ycbcr2rgb(uint8(im_h_ycbcr));

                    figure(app.UIFigure);
                    imshow(app.im_h,'Parent',app.UIAxes_2);
                    end
                end
                if app.umethod==3
                    % LR images are obtained by down-sampling the HR images directly along both the horizontal and vertical directions by a factor of 2, 3, or 4.
                    % The initial image must be resized before processing to to avoid matrix size inequality at the end of the process
                    transform = app.im(1:scale:end,1:scale:end,:);
                    Rsize = size(transform,1);
                    Csize = size(transform,2);
                    Rfactor = size((1:2:Rsize),2);
                    Cfactor = size((1:2:Csize),2);
                    if scale==2
                        Rsize = 4*Rfactor;
                        Csize = 4*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    elseif scale==3
                        Rsize = 6*Rfactor;
                        Csize = 6*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    else
                        Rsize = 8*Rfactor;
                        Csize = 8*Cfactor;
                        app.im = imresize(app.im,[Rsize Csize]);
                    end
                    LR  =  app.im(1:scale:end,1:scale:end,:);  

                    if  ch == 3
                    % change color space, work on illuminance only
                    im_l_ycbcr = rgb2ycbcr(LR);
                    im_l_y = im_l_ycbcr(:, :, 1);
                    im_l_cb = im_l_ycbcr(:, :, 2);
                    im_l_cr = im_l_ycbcr(:, :, 3);
                    im_l_y=double(im_l_y);
                    im_l_cb=double(im_l_cb);
                    im_l_cr=double(im_l_cr);
                    
                    im_h_y = imresize(im_l_y, scale, 'bilinear');
                    im_h_cb = imresize(im_l_cb, scale, 'bilinear');
                    im_h_cr = imresize(im_l_cr, scale, 'bilinear');

                    [nrow, ncol] = size(im_h_y);
                    im_h_ycbcr = zeros([nrow, ncol, 3]);
                    im_h_ycbcr(:, :, 1) = im_h_y;
                    im_h_ycbcr(:, :, 2) = im_h_cb;
                    im_h_ycbcr(:, :, 3) = im_h_cr;
                    app.im_h = ycbcr2rgb(uint8(im_h_ycbcr));

                    figure(app.UIFigure);
                    imshow(app.im_h,'Parent',app.UIAxes_2);
                    end
%                 imshow(LR,'Parent',app.UIAxes);
                end
        end

        % Button pushed function: ResetParameterButton
        function ResetParameterButtonPushed(app, event)
            app.AlphaEditField.Value = 0;
            app.BetaEditField.Value = 0;
            app.GammaEditField.Value = 0;
            app.ScalingFactorEditField.Value = 0;
        end

        % Button pushed function: PSNRButton
        function PSNRButtonPushed(app, event)
            if app.umethod == 1
            PSNR_Result = psnr1(app.im,app.im_h);
            app.PSNRTextArea.Value = num2str(PSNR_Result);
            else
            PSNR_Result = psnr(app.im,app.im_h);
            app.PSNRTextArea.Value = num2str(PSNR_Result);
            end
        end

        % Button pushed function: SSIMButton
        function SSIMButtonPushed(app, event)
            SSIM_Result = ssim(app.im,app.im_h);
            app.SSIMTextArea.Value = num2str(SSIM_Result);
        end

        % Button pushed function: ClearModifiedImageButton
        function ClearModifiedImageButtonPushed(app, event)
            cla(app.UIAxes_2,"reset");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.Panel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.OriginalImageLabel = uilabel(app.Panel);
            app.OriginalImageLabel.Text = 'Original Image';
            app.SuperResolutionImageLabel = uilabel(app.Panel);
            app.SuperResolutionImageLabel.Text = 'Super Resolution Image';
            app.ClearModifiedImageButton = uibutton(app.Panel, 'push');
            app.ClearModifiedImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearModifiedImageButtonPushed, true);
            app.ClearModifiedImageButton.Text = 'Clear Modified Image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'SUPER RESOLUSI CITRA SATELIT'; 'MENGGUNAKAN METODE '; 'INTERPOLASI FRAKTAL RASIONAL'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Template', 'logo_matematika-its-besar.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Template', 'Logo-ITS-1-300x185.png');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.ShapeParameterPanel = uipanel(app.UIFigure);
            app.ShapeParameterPanel.Title = 'Shape Parameter';
            app.AlphaEditFieldLabel = uilabel(app.ShapeParameterPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.ShapeParameterPanel, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.ShapeParameterPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.ShapeParameterPanel, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.ShapeParameterPanel);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.ShapeParameterPanel, 'numeric');
            app.ScalingFactorEditFieldLabel = uilabel(app.ShapeParameterPanel);
            app.ScalingFactorEditFieldLabel.Text = {'Scaling'; 'Factor'};
            app.ScalingFactorEditField = uieditfield(app.ShapeParameterPanel, 'numeric');
            app.ResetParameterButton = uibutton(app.ShapeParameterPanel, 'push');
            app.ResetParameterButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParameterButtonPushed, true);
            app.ResetParameterButton.Tag = 'btn';
            app.ResetParameterButton.Text = 'Reset Parameter';
            app.InputImageButton = uibutton(app.UIFigure, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Tag = 'btn';
            app.InputImageButton.Text = 'Input Image';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Tag = 'btn';
            app.ClearAllButton.Text = 'Clear All';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'btn';
            app.StartButton.Text = 'Start ';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tag = 'btn';
            app.CloseButton.Text = 'Close';
            app.Panel_2 = uipanel(app.UIFigure);
            app.PSNRTextAreaLabel = uilabel(app.Panel_2);
            app.PSNRTextAreaLabel.Text = 'PSNR';
            app.PSNRTextArea = uitextarea(app.Panel_2);
            app.SSIMTextAreaLabel = uilabel(app.Panel_2);
            app.SSIMTextAreaLabel.Text = 'SSIM';
            app.SSIMTextArea = uitextarea(app.Panel_2);
            app.PSNRButton = uibutton(app.UIFigure, 'push');
            app.PSNRButton.ButtonPushedFcn = createCallbackFcn(app, @PSNRButtonPushed, true);
            app.PSNRButton.Tag = 'btn';
            app.PSNRButton.Text = 'PSNR';
            app.SSIMButton = uibutton(app.UIFigure, 'push');
            app.SSIMButton.ButtonPushedFcn = createCallbackFcn(app, @SSIMButtonPushed, true);
            app.SSIMButton.Tag = 'btn';
            app.SSIMButton.Text = 'SSIM';
            app.DownsamplePanel = uipanel(app.UIFigure);
            app.DownsamplePanel.Title = 'Downsample';
            app.UpsampleMethodDropDownLabel = uilabel(app.DownsamplePanel);
            app.UpsampleMethodDropDownLabel.Text = {'Upsample'; 'Method'};
            app.UpsampleMethodDropDown = uidropdown(app.DownsamplePanel);
            app.UpsampleMethodDropDown.Items = {'Fraktal', 'Bicubic', 'Bilinier'};
            app.UpsampleMethodDropDown.Value = 'Fraktal';
            app.ScaleDropDownLabel = uilabel(app.DownsamplePanel);
            app.ScaleDropDownLabel.Text = 'Scale';
            app.ScaleDropDown = uidropdown(app.DownsamplePanel);
            app.ScaleDropDown.Items = {'2', '3', '4'};
            app.ScaleDropDown.Value = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Demo_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end